classdef HoloApp_Lito < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        SLMconnectedLamp                matlab.ui.control.Lamp
        SLMconnectedLampLabel           matlab.ui.control.Label
        TargetSizepixelsDropDown        matlab.ui.control.DropDown
        TargetSizepixelsDropDownLabel   matlab.ui.control.Label
        TargetfilenameEditField         matlab.ui.control.EditField
        TargetfilenameLabel             matlab.ui.control.Label
        PhasefilenameEditField          matlab.ui.control.EditField
        PhasefilenameEditFieldLabel     matlab.ui.control.Label
        SavetargetimageButton           matlab.ui.control.Button
        SavePhasemapButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Step1Tab                        matlab.ui.container.Tab
        ApplyoffsetsButton              matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        ScaleTargetEditField            matlab.ui.control.NumericEditField
        ScaleTargetEditFieldLabel       matlab.ui.control.Label
        ChooseoneofthefollowingtargetsLabel  matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        VerticalposSlider_2             matlab.ui.control.Slider
        VerticalposSlider_2Label        matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        HorizontalposSlider_2           matlab.ui.control.Slider
        HorizontalposSliderLabel_2      matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        ImageSelectionListBoxLabel      matlab.ui.control.Label
        Step23Tab                       matlab.ui.container.Tab
        ApplytoSLMLabel                 matlab.ui.control.Label
        ApplytoSLMButton                matlab.ui.control.Button
        WavelegthnmDropDown             matlab.ui.control.DropDown
        WavelegthnmDropDownLabel        matlab.ui.control.Label
        RefreshButton_2                 matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        OraddalreadygeneratedphasemapLabel  matlab.ui.control.Label
        ApplyGSroutineButton            matlab.ui.control.Button
        BeamdiametermmEditField         matlab.ui.control.NumericEditField
        BeamdiametermmEditFieldLabel    matlab.ui.control.Label
        iterationsforGSEditField        matlab.ui.control.NumericEditField
        iterationsforGSEditFieldLabel   matlab.ui.control.Label
        GerchbergSaxtonRoutineLabel     matlab.ui.control.Label
        ExtramovingcircleTab            matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        DiametermmonSLMSurfaceEditField  matlab.ui.control.NumericEditField
        DiametermmonSLMSurfaceEditFieldLabel  matlab.ui.control.Label
        VerticalposSlider               matlab.ui.control.Slider
        VerticalposSliderLabel          matlab.ui.control.Label
        HorizontalposSlider             matlab.ui.control.Slider
        HorizontalposSliderLabel        matlab.ui.control.Label
        InfoLabel                       matlab.ui.control.Label
        CreateacirculartargetofvariablediameterandpositionLabel  matlab.ui.control.Label
        AssumedSLMcentre961577Label     matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        ApplychangesButton              matlab.ui.control.Button
        StartwithacircleButton          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ExtramovemanycirclesTab         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ConnecttoSLMrealtimeSwitch      matlab.ui.control.RockerSwitch
        ConnecttoSLMrealtimeSwitchLabel  matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        OpacitySlider                   matlab.ui.control.Slider
        OpacitySliderLabel              matlab.ui.control.Label
        DiametermmLabel                 matlab.ui.control.Label
        CircleselectionDropDown         matlab.ui.control.DropDown
        CircleselectionDropDownLabel    matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        xxxyyyLabel                     matlab.ui.control.Label
        InfoaboutselectedcircleLabel    matlab.ui.control.Label
        PositionhvLabel_2               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ApplychangesButton_2            matlab.ui.control.Button
        RemovelastcircleButton          matlab.ui.control.Button
        CreatemultiplecirclesandmovethemaroundLabel  matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        DiametermmonSLMSurfaceEditField_2  matlab.ui.control.NumericEditField
        DiametermmonSLMSurfaceEditFieldLabel_2  matlab.ui.control.Label
        VerticalposSlider_3             matlab.ui.control.Slider
        VerticalposSliderLabel_2        matlab.ui.control.Label
        HorizontalposSlider_3           matlab.ui.control.Slider
        HorizontalposSliderLabel_3      matlab.ui.control.Label
        AddcircleButton                 matlab.ui.control.Button
        ExtraAddmultiplepicturesTab     matlab.ui.container.Tab
        RefreshButton_3                 matlab.ui.control.Button
        NoselectedimageLabel            matlab.ui.control.Label
        SelectimagesformultipletargetscontinuesinnexttabLabel  matlab.ui.control.Label
        AddImageButton                  matlab.ui.control.Button
        ChooseoneofthefollowingtargetsLabel_2  matlab.ui.control.Label
        ListBox_3                       matlab.ui.control.ListBox
        UIAxes_2                        matlab.ui.control.UIAxes
        ExtramovingpicturesTab          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ScaleimgEditField               matlab.ui.control.NumericEditField
        DiametermmonSLMSurfaceEditFieldLabel_3  matlab.ui.control.Label
        imageSelectionDropDown          matlab.ui.control.DropDown
        imageSelectionDropDownLabel     matlab.ui.control.Label
        HorizontalposSliderLabel_5      matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ScalingLabel                    matlab.ui.control.Label
        RemoveselectedImageButton       matlab.ui.control.Button
        xxxyyyLabel_2                   matlab.ui.control.Label
        InfoaboutselectedimageLabel     matlab.ui.control.Label
        PositionhvLabel                 matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ApplychangesButton_3            matlab.ui.control.Button
        MoveandscaleanyselectedimageLabel  matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        VerticalposSlider_4             matlab.ui.control.Slider
        HorizontalposSlider_4           matlab.ui.control.Slider
        HorizontalposSliderLabel_4      matlab.ui.control.Label
        MappingfibresTab                matlab.ui.container.Tab
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        SizeofsquarepixsEditField       matlab.ui.control.NumericEditField
        SizeofsquarepixsEditFieldLabel  matlab.ui.control.Label
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        HorrangeSlider                  matlab.ui.control.RangeSlider
        HorrangeSliderLabel             matlab.ui.control.Label
        VerrangeSlider                  matlab.ui.control.RangeSlider
        VerrangeSliderLabel             matlab.ui.control.Label
        FoldernameEditField             matlab.ui.control.EditField
        FoldernameEditFieldLabel        matlab.ui.control.Label
        TimeperacqsEditField            matlab.ui.control.NumericEditField
        TimeperacqsEditFieldLabel       matlab.ui.control.Label
        StartexpButton                  matlab.ui.control.Button
        EasyHoloAppLabel                matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        outDir % Output directory: where phase images can be stored if the User presses the "Save Output" button
        tgDir  % Target directory: where target images are stored
        filesList % Available target files
        outFilesList % List of available phase maps
        target % Current target image of SLM, shown in UIAxes (left panel)
        phase % Phase image, shown in UIAxes2 (right panel)
        n_iter % Number of GS routine iterations
        SLM_dims % Dimensions of SLM
        SLM_pitch
        beam_diameter % Diameter of the incident beam to the SLM (in m)
        out_filename % Output file name: name of the image file if the User presses the "Save Output" button
        tgt_filename % Name of the file used for the target - it will change if the image is moved or scaled
        tgt_filename_original % original filename of the target
        SLM_connected % Logical variable, indicated whether a correct connection has been established with the SLM
        selected_img % If the User has chosen an image from the tgt direectory, this variable corresponds to this image
        beam_gauss % Amplitude distribution of light hitting the SLM
        unshifted_img 
        % % square_target % Float to switch between a 1152x1152 or 1920x1152 target image
        desired_dims  % dimensions of the target image

        % SLM connectivity variables
        num_boards_found 
        wait_For_Trigger
        flip_immediate
        timeout_ms
        SDK
        OutputPulseImageFlip
        OutputPulseImageRefresh
        SLM_wavelength

        % Circle shifting/resizing variables
        fp_diam % Diameter of the target circle (on the SLM surface) in m
        centre_SLM % Centre coordinates of the SLM
        offset_target % Offset from centre at which the target is found [Hor Vert]
        fp_diam_pixels % Diameter of target circle in # pixels
        offset_matrix % Matrix with the different offsets of different circles [Hor Vert]
        diam_matrix % Array with the diameters of the different circles
        diam_pixels_matrix % Array with the diameters of the different circles in # pixels
        opacity_matrix % Array with the opacity of each circle (0 to 1)
        update_SLM % Logical var - to check whether to update SLM in real time or not
        
        % Image shifting and resizing variables
        poss_img % saves the image that is being viewed by the user in UIAxes_2
        img_info % Cell containing the values of all the selected images
        offset_mat_img % matrix with the offsets of all the imgs starting at (0,0) top left corner
        scale_mat_img % array with the scaling of each of the selected images
        img_info_original % for resizing

        % Mapping fibres variables
        exp_name % String with folder where data is going to be saved
        fibre_dir % string with relative path to where fibre images from the camera are saved
        time_on % int for time for camera acquisition (per image that is sent to the SLM) in seconds
    end
    
    
    methods (Access = private)
        
        function target = resize_to_SLM(app, selected_img) 
            % This function resizes any image to the SLM dimensions
            
            % get the dims of the image
            dim_img = size(selected_img);
            
            % Initialise padding variables (this is for smaller images)
            padY=0;
            padX=0;
        

            % Determine which dimension will need padding
            log_arr = dim_img<=app.SLM_dims;

            if log_arr(1)
                padY = app.SLM_dims(1)-dim_img(1);
            else
                disp('Image will be cut in y axis');
            end

            if log_arr(2)
                padX = app.SLM_dims(2)-dim_img(2);
            else
                disp('Image will be cut in x axis');
            end
            
            % Add padding if needed
            target = padarray(selected_img,round([padY padX]./2),0,'both');
            
            % Crop image to SLM dimensions if needed
            win1 = centerCropWindow2d(size(target),app.SLM_dims);
            target = imcrop(target,win1);

        end
        
        function n = get_num_decimal_pts(~, decimal)
            % This function calculates how many decimal points a number
            % carries

                n=0;
                while (floor(decimal*10^n)~=decimal*10^n)
                    n=n+1;
                end
            
        end
        
        function disp_circ_info(app,idx)
            % This function displays the offset and diameter of a circle in
            % a specified rectangle in the tab 'Extra: move many circles'
            % It also sets the sliders and edit fields to the right offset
            % for each circle

            % Get the diameter and offset info about a specific circle
            diam = app.diam_matrix(idx)*1e3;
            hor = app.offset_matrix(idx,1);
            vert = -app.offset_matrix(idx,2);
            
            % Display the info
            app.xxxyyyLabel.Text = strcat("[",num2str(hor),",",num2str(vert),"]");
            app.Label_3.Text = num2str(diam);
            
            % Change the sliders accordingly
            app.DiametermmonSLMSurfaceEditField_2.Value = diam;
            app.HorizontalposSlider_3.Value = hor;
            app.VerticalposSlider_3.Value = vert;
            app.EditField_6.Value = hor;
            app.EditField_7.Value = vert;
        end
        
        function app = draw_circles(app)
        % Go through all the circles the user wants and draw them on UIAxes

            l = size(app.offset_matrix,1);

            canvas = zeros(app.desired_dims);

            % Go thourgh all the circles and draw them
            for a = 1:l
                canvas=insertShape(canvas,'filled-circle',[app.centre_SLM+app.offset_matrix(a,:) round(app.diam_pixels_matrix(a)/2)],'ShapeColor',[1,1,1],'Opacity',app.opacity_matrix(a));
            end

            app.target = mean(canvas,3);
            imshow(app.target,'Parent',app.UIAxes);
            
            if app.update_SLM
                update_SLM_fun(app)
            end
        end
        
        function app = show_all_imgs(app)
        % Display all the selected images in UIAxes

            l = length(app.img_info);
            
            canvas = zeros([app.desired_dims l ]);
            x_lim = app.desired_dims(2);

            for a=1:l
                
                flag_no_image = 0; % Float in case you shift an image so 
                % much you don't display it anymore
                
                % Get info of the image you are working with
                dims = size(app.img_info{a});
                img = app.img_info{a};
                
                % Calculate the corner coordinates of the image
                %(because I am a perfectionist and want to add images at
                % the centre of the display)
                x_start = app.centre_SLM(1)-floor(dims(2)/2)+app.offset_mat_img(a,1);
                x_end = x_start+dims(2)-1;
                y_start = app.centre_SLM(2)-floor(dims(1)/2)+app.offset_mat_img(a,2);
                y_end = y_start+dims(1)-1;
                
                % If I have shifted an image so much to the left that I
                % need to cut it from the left, do so here:
                if x_start<1
                    x_start_img = 2-x_start; % Cutting image
                    x_start=1; % The image will start from the leftmost side of the display
                else
                    x_start_img = 1; % If I haven't shifted the image enough
                    % for this to happen, the image will not be cut
                end

                % If I have shifted an image so much to the right that I
                % need to cut it from the right, do so here:
                if x_end>x_lim
                    x_end_img = dims(2)-(x_end-x_lim); % Cutting image
                    x_end=x_lim; % The image will be displayed up to the 
                    % rightmost side of the target
                else
                    x_end_img=dims(2); % If I haven't shifted the image enough
                    % for this to happen, the image will not be cut
                end
                
                % Same thing as before but for y axis
                if y_start<1
                    y_start_img = 2-y_start;
                    y_start=1;
                else
                    y_start_img = 1;
                end

                if y_end>app.desired_dims(1)
                    y_end_img = dims(1)-(y_end-app.desired_dims(1));
                    y_end=app.desired_dims(1);
                else
                    y_end_img=dims(1);
                end
                
                % Check if I will not be displaying an image at all due to
                % shifting it too much
                if x_start_img>dims(2)||y_start_img>dims(1)
                    flag_no_image=1;
                end
                
                % If the image is (even partially) displayed, draw it on
                % a blank canvas 
                if ~flag_no_image
                    canvas(y_start:y_end,x_start:x_end,a) = canvas(y_start:y_end,x_start:x_end,a)+img(y_start_img:y_end_img,x_start_img:x_end_img);
                end
            end

            % Sum all the images together
            canvas=sum(canvas,3);

            % Cap at 255
            canvas(canvas>255)=255;
            
            app.target = canvas;
            imagesc(app.UIAxes,app.target);
        end

        function app = update_imgs(app)
        % Show the previous target image plus the new added one
        % This function is not being used right now
            
            dims = size(app.img_info{end});
           

            log_arr = dims<=app.desired_dims;
            
            % If the current image is smaller than the considered target,
            % position the image in the centre without cutting it
            if all(log_arr)
                x_start = app.centre_SLM(1)-floor(dims(2)/2);
                x_end = x_start+dims(2)-1;
                y_start = app.centre_SLM(2)-floor(dims(1)/2);
                y_end = y_start+dims(1)-1;
                app.target(y_start:y_end,x_start:x_end) = app.target(y_start:y_end,x_start:x_end)+app.img_info{end};
            else
                % Else, crop it and add it to the previous target image
                % if app.square_target
                %     cropped_img = squaring_img(app.img_info{end},app.SLM_dims(1));
                % else
                %     cropped_img = resize_to_SLM(app,app.img_info{end});
                % end
                
                if isequal(app.desired_dims,[1152 1152])

                    cropped_img = squaring_img(app.img_info{end},app.SLM_dims(1));

                elseif isequal(app.desired_dims,[1152 1920])

                    cropped_img = resize_to_SLM(app,app.img_info{end});

                else
                    %% Function is not used so I'm not coding this for now
                    % win = centerCropWindow2d(dims,[1152 1920]);
                    % cropped_phase = imcrop(wrapped_phase,win);
                end


                app.target = app.target+cropped_img;
            end

            app.target(app.target>255)=255;

            imagesc(app.UIAxes,app.target);
        end
        
        function disp_img_info(app,idx)
            % This function displays the offset and scale of an image
            % in the tab 'Extra: moving pictures'
            % It also sets the sliders and edit fields to the right offset
            % for each image
            
            % Get the info about the image we want
            scale = app.scale_mat_img(idx);
            hor = app.offset_mat_img(idx,1);
            vert = -app.offset_mat_img(idx,2);
            
            % Display it
            app.xxxyyyLabel_2.Text = strcat("[",num2str(hor),",",num2str(vert),"]");
            app.Label_5.Text = num2str(scale);
            
            
            % Set the sliders and edit fields to the correct value
            app.ScaleimgEditField.Value = scale;
            app.HorizontalposSlider_4.Value = hor;
            app.VerticalposSlider_4.Value = vert;
            app.EditField_8.Value = hor;
            app.EditField_9.Value = vert; 
        end
        
        function app = switch_to_square_target(app)
            % Handling switching between square and rectangular targets

            value = app.TargetSizepixelsDropDown.Value;
            if strcmp(value,'1152x1152')
                % app.square_target = true;
                app.desired_dims = [1152 1152];

                app.UIAxes.PlotBoxAspectRatio = [1152 1152 1];
                app.UIAxes3.PlotBoxAspectRatio = [1152 1152 1];

                app.HorizontalposSlider_2.Limits = [-1152, 1152];
                app.EditField_4.Limits  = [-1152, 1152];

                app.HorizontalposSlider_3.Limits = [-576, 576];
                app.EditField_6.Limits  = [-576, 576];

                app.centre_SLM = [577,577];
                
                app.HorizontalposSlider.Limits = [-576, 576];
                app.EditField.Limits = [-576, 576];

                app.HorizontalposSlider_4.Limits = [-1152, 1152];
                app.EditField_8.Limits  = [-1920, 1920];

                app.HorrangeSlider.Limits = [-576,575];
                app.VerrangeSlider.Limits = [-576, 575];
                app.EditField_11.Limits = [-576, 575];
                app.EditField_13.Limits = [-576, 575];
                app.EditField_12.Limits = [-576, 575];
                app.EditField_14.Limits = [-576, 575];

            elseif  strcmp(value,'1920x1152')
                % app.square_target = false;
                app.desired_dims = [1152 1920];

                app.UIAxes.PlotBoxAspectRatio = [1920 1152 1];
                app.UIAxes3.PlotBoxAspectRatio = [1920 1152 1];

                app.HorizontalposSlider_2.Limits = [-1920, 1920];
                app.EditField_4.Limits  = [-1920, 1920];

                app.centre_SLM = [961,577];

                app.HorizontalposSlider.Limits = [-960, 960];
                app.EditField.Limits = [-960, 960];

                app.HorizontalposSlider_3.Limits = [-960, 960];
                app.EditField_6.Limits  = [-960, 960];

                app.HorizontalposSlider_4.Limits = [-1920, 1920];
                app.EditField_8.Limits  = [-1920, 1920];

                app.HorrangeSlider.Limits = [-960,959];
                app.VerrangeSlider.Limits = [-576, 575];
                app.EditField_11.Limits = [-576, 575];
                app.EditField_13.Limits = [-576, 575];
                app.EditField_12.Limits = [-960,959];
                app.EditField_14.Limits = [-960,959];

            elseif strcmp(value,'1920x1920')

                app.desired_dims = [1920 1920];

                app.UIAxes.PlotBoxAspectRatio = [1920 1920 1];
                app.UIAxes3.PlotBoxAspectRatio = [1920 1920 1];

                app.HorizontalposSlider_2.Limits = [-1920, 1920];
                app.EditField_4.Limits  = [-1920, 1920];

                app.centre_SLM = [961,961];

                app.HorizontalposSlider.Limits = [-960, 960];
                app.EditField.Limits = [-960, 960];

                app.HorizontalposSlider_3.Limits = [-960, 960];
                app.EditField_6.Limits  = [-960, 960];

                app.HorizontalposSlider_4.Limits = [-1920, 1920];
                app.EditField_8.Limits  = [-1920, 1920];

                app.HorrangeSlider.Limits = [-960,959];
                app.VerrangeSlider.Limits = [-960,959];
                app.EditField_11.Limits = [-960,959];
                app.EditField_13.Limits = [-960,959];
                app.EditField_12.Limits = [-960,959];
                app.EditField_14.Limits = [-960,959];

            end
        end
        
        function update_SLM_fun(app)
            calc_GS_routine(app)
            send_info_to_SLM(app);

            
        end
        
        function send_info_to_SLM(app)
            if app.num_boards_found.val > 0
                disp(['Found ',num2str(app.num_boards_found.val),' SLM controller(s)'])
                board_number =1;
                disp('Getting SLM information.')
                height = calllib('Blink_C_wrapper', 'Get_image_height', board_number);
                width = calllib('Blink_C_wrapper', 'Get_image_width', board_number);
                depth = calllib('Blink_C_wrapper', 'Get_image_depth', board_number); % = bits per pixel
                Bytes = depth/8;
                ImageOne = libpointer('uint8Ptr', zeros(width*height*Bytes,1)); 
                
                SLM_phase = app.phase.*255./(2*pi);

                %% Send info to SLM
                disp('Loading LUT.')
                if strcmp(app.SLM_wavelength,'532')
                    calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6661_at532_PCIe.LUT');
                    WFC = double(imread('..\lib\slm6661_at532_WFC.bmp'));
                else
                    calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6661_at1064_PCIe.LUT');
                    WFC = double(imread('..\lib\slm6661_at1064_WFC.bmp'));
                end
                ImageOne.value = uint8(mod(SLM_phase' + WFC',256));
                calllib('Blink_C_wrapper', 'Write_image', board_number, ImageOne, width*height*Bytes, app.wait_For_Trigger, app.flip_immediate, app.OutputPulseImageFlip, app.OutputPulseImageRefresh, app.timeout_ms);
                calllib('Blink_C_wrapper', 'ImageWriteComplete', board_number, app.timeout_ms);  
                disp('You should see something on your focal plane now :)')
            else
                disp('Not connected to SLM!')
            end
        end
        
        function calc_GS_routine(app)
            % Calculate the phase map to put into SLM using the
            % Gerchberg-Saxton routine

            img_dims = size(app.target);

            % Get the irradiation pattern on SLM
            slm_beam_rad = round(app.beam_diameter/(2*app.SLM_pitch)); 
            [slmX, slmY] = meshgrid(-round(img_dims(2)/2):round(img_dims(2)/2)-1,-round(img_dims(1)/2):round(img_dims(1)/2)-1); 
            slmR = sqrt(slmX.^2 + slmY.^2);
            app.beam_gauss = exp(-2*slmR.^2/slm_beam_rad^2);

            % Do GS routine
            squared_phase = GS_routine(app.target,app.beam_gauss,app.n_iter);
            wrapped_phase = wrapTo2Pi(squared_phase);
            
            % Resize the  image according to the target dimensions
            if isequal(app.desired_dims,[1152 1152])
                app.phase = pad_to_SLM(wrapped_phase,app.SLM_dims);
            elseif isequal(app.desired_dims,[1152 1920])
                app.phase = wrapped_phase;
            else
                win = centerCropWindow2d(app.desired_dims,[1152 1920]);
                app.phase = imcrop(wrapped_phase,win);
            end
            
            % Show result
            imagesc(app.UIAxes2,app.phase);
            
            % Show estimated result in focal plane
            est_result = fft2(app.beam_gauss.*exp(1i.*squared_phase));
            imagesc(app.UIAxes3,abs(est_result));
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Show the posible target images found in the img\tgt folder
            app.tgDir = '..\img\tgt\';
            app.filesList = dir(app.tgDir);
            app.ListBox_2.Items = {app.filesList.name}';
            app.ListBox_3.Items = {app.filesList.name}';

            % Initialise variables
            app.n_iter= app.iterationsforGSEditField.Value;
            app.SLM_dims = [1152,1920];
            app.SLM_pitch = 9.2e-6;
            app.beam_diameter = app.BeamdiametermmEditField.Value;
            app.beam_diameter = app.beam_diameter*1e-3;
            app.out_filename = app.PhasefilenameEditField.Value;
            app.SLM_connected = false;
            app.target = zeros(app.SLM_dims);
            app.SLM_wavelength = app.WavelegthnmDropDown.Value;
            
            switch_to_square_target(app);

            % Display the possible phase maps found in img\out
            app.outDir = '..\img\out\';
            app.outFilesList = dir(app.outDir);
            app.ListBox.Items = {app.outFilesList.name}';
            

            %% Configuring figures           
            axis(app.UIAxes, 'tight')
           
            axis(app.UIAxes2, 'tight')
            axis(app.UIAxes3, 'tight')
            axis(app.UIAxes_2, 'tight')
            colormap(app.UIAxes2, 'gray');
            colormap(app.UIAxes_2, 'gray');
            colormap(app.UIAxes3, 'gray');
            colormap(app.UIAxes, 'gray');
            app.UIAxes2.PlotBoxAspectRatio = [1920,1152,1];
            

            %% Extra tab ('moving circle') variables
            app.fp_diam = app.DiametermmonSLMSurfaceEditField.Value*1e-3;
            app.fp_diam_pixels = round(app.fp_diam/app.SLM_pitch);
            app.offset_target = zeros(1,2);

            app.update_SLM = app.ConnecttoSLMrealtimeSwitch.ValueIndex-1;
            
            % app.centre_SLM = [961,577];


            %% Extra tab ('moving images') variables
            app.img_info = {};
            app.img_info_original = {};

            %% 'Mapping fibres' tab setup
            app.VerrangeSlider.Value = [1,100];
            app.EditField_11.Value = 100;
            app.EditField_13.Value = 1;
            app.HorrangeSlider.Value = [1,100];
            app.EditField_12.Value = 1;
            app.EditField_14.Value = 100;
            app.exp_name = app.FoldernameEditField.Value;

            app.fibre_dir = '..\img\fibre_imgs\';
            app.time_on = app.TimeperacqsEditField.Value;

            %% SLM connection attempt
            bit_depth = 12; %bit depth = 8 for small 512, 12 for 1920
            app.num_boards_found = libpointer('uint32Ptr', 0);
            constructed_okay = libpointer('int32Ptr', 0);
            is_nematic_type = 1;
            RAM_write_enable = 1;
            use_GPU = 0;
            max_transients = 10;
            app.wait_For_Trigger = 0; % This feature is user-settable; use 1 for 'on' or 0 for 'off'
            
            app.flip_immediate = 0; % Only supported on the 1024
            app.timeout_ms = 5000;
            app.SDK = struct();
            
            app.OutputPulseImageFlip = 0;
            app.OutputPulseImageRefresh = 0;
            reg_lut = libpointer('string');
            if ~libisloaded('Blink_C_wrapper')
               loadlibrary('../lib/Blink_C_wrapper.dll', '../lib/Blink_C_wrapper.h');
            end
            if ~libisloaded('ImageGen')
                loadlibrary('../lib/ImageGen.dll', '../lib/ImageGen.h');
            end
            disp('Libraries loaded.')
            disp('Trying to connect')
            
            disp('Trying to create SDK.')
            calllib('Blink_C_wrapper', 'Create_SDK', bit_depth, app.num_boards_found, constructed_okay, is_nematic_type, RAM_write_enable, use_GPU, max_transients, reg_lut);
            if constructed_okay ~= 1
                disp('SDK NOT constructed ok.')
            end
            
            disp(['Num boards: ', num2str(app.num_boards_found.val)])
            
            if app.num_boards_found.val > 0
                app.SLM_connected = true;
                disp('Blink SDK was successfully constructed');
                app.SLMconnectedLamp.Color = [112, 209, 109]./255;
            end

        end

        % Button pushed function: ApplyGSroutineButton
        function ApplyGSroutineButtonPushed(app, event)
            
            
            % % Calculate the phase map to put into SLM using the
            % % Gerchberg-Saxton routine
            % 
            % img_dims = size(app.target);
            % 
            % % Get the irradiation pattern on SLM
            % slm_beam_rad = round(app.beam_diameter/(2*app.SLM_pitch)); 
            % [slmX, slmY] = meshgrid(-round(img_dims(2)/2):round(img_dims(2)/2)-1,-round(img_dims(1)/2):round(img_dims(1)/2)-1); 
            % slmR = sqrt(slmX.^2 + slmY.^2);
            % app.beam_gauss = exp(-2*slmR.^2/slm_beam_rad^2);
            % 
            % % Do GS routine
            % squared_phase = GS_routine(app.target,app.beam_gauss,app.n_iter);
            % wrapped_phase = wrapTo2Pi(squared_phase);
            % 
            % % Resize the  image according to the target dimensions
            % if isequal(app.desired_dims,[1152 1152])
            %     app.phase = pad_to_SLM(wrapped_phase,app.SLM_dims);
            % elseif isequal(app.desired_dims,[1152 1920])
            %     app.phase = wrapped_phase;
            % else
            %     win = centerCropWindow2d(app.desired_dims,[1152 1920]);
            %     app.phase = imcrop(wrapped_phase,win);
            % end
            % 
            % % Show result
            % imagesc(app.UIAxes2,app.phase);
            % 
            % % Show estimated result in focal plane
            % est_result = fft2(app.beam_gauss.*exp(1i.*squared_phase));
            % imagesc(app.UIAxes3,abs(est_result));
            calc_GS_routine(app);
        end

        % Value changed function: iterationsforGSEditField
        function iterationsforGSEditFieldValueChanged(app, event)
            app.n_iter = app.iterationsforGSEditField.Value;
        end

        % Value changed function: BeamdiametermmEditField
        function BeamdiametermmEditFieldValueChanged(app, event)
            app.beam_diameter = app.BeamdiametermmEditField.Value .* 1e-3;
        end

        % Button pushed function: SavePhasemapButton
        function SavePhasemapButtonPushed(app, event)
            img_to_save = uint8((app.phase).*255./(2*pi));
            imwrite(img_to_save,[app.outDir,app.out_filename,'.bmp'])
        end

        % Value changed function: PhasefilenameEditField
        function PhasefilenameEditFieldValueChanged(app, event)
            app.out_filename = app.PhasefilenameEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.SLM_connected
                calllib('Blink_C_wrapper', 'Delete_SDK');
                disp('Deleting SDK and closing app')
                if libisloaded('Blink_C_wrapper')
                    unloadlibrary('Blink_C_wrapper');
                end
                
                if libisloaded('ImageGen')
                    unloadlibrary('ImageGen');
                end
            else
                disp('Closing app. No SDK present.')
            end
            delete(app)
        end

        % Button pushed function: ApplytoSLMButton
        function ApplytoSLMButtonPushed(app, event)

            % if app.num_boards_found.val > 0
            %     disp(['Found ',num2str(app.num_boards_found.val),' SLM controller(s)'])
            %     board_number =1;
            %     disp('Getting SLM information.')
            %     height = calllib('Blink_C_wrapper', 'Get_image_height', board_number);
            %     width = calllib('Blink_C_wrapper', 'Get_image_width', board_number);
            %     depth = calllib('Blink_C_wrapper', 'Get_image_depth', board_number); % = bits per pixel
            %     Bytes = depth/8;
            %     ImageOne = libpointer('uint8Ptr', zeros(width*height*Bytes,1)); 
            % 
            %     SLM_phase = app.phase.*255./(2*pi);
            % 
            %     %% Send info to SLM
            %     disp('Loading LUT.')
            %     if strcmp(app.SLM_wavelength,'532')
            %         calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6661_at532_PCIe.LUT');
            %         WFC = double(imread('..\lib\slm6661_at532_WFC.bmp'));
            %     else
            %         calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6661_at1064_PCIe.LUT');
            %         WFC = double(imread('..\lib\slm6661_at1064_WFC.bmp'));
            %     end
            %     ImageOne.value = uint8(mod(SLM_phase' + WFC',256));
            %     calllib('Blink_C_wrapper', 'Write_image', board_number, ImageOne, width*height*Bytes, app.wait_For_Trigger, app.flip_immediate, app.OutputPulseImageFlip, app.OutputPulseImageRefresh, app.timeout_ms);
            %     calllib('Blink_C_wrapper', 'ImageWriteComplete', board_number, app.timeout_ms);  
            %     disp('You should see something on your focal plane now :)')
            % else
            %     disp('Not connected to SLM!')
            % end
            send_info_to_SLM(app)
        end

        % Button pushed function: StartwithacircleButton
        function StartwithacircleButtonPushed(app, event)
            % Start with a black image with a white circle in the middle

            blank_img = zeros(app.desired_dims);
            app.offset_target(1)= round(app.HorizontalposSlider.Value); % Get x and y offsets
            app.offset_target(2)= -round(app.VerticalposSlider.Value);
            
            % Produces an RGB image with the circle in the middle (note: it is 3D)
            h=insertShape(blank_img,'filled-circle',[app.centre_SLM+app.offset_target round(app.fp_diam_pixels/2)],'ShapeColor',[1,1,1],'Opacity',1);
            
            % Set resulting image as target
            app.target = mean(h,3);
            
            imshow(app.target,'Parent',app.UIAxes);
            
        end

        % Value changed function: HorizontalposSlider
        function HorizontalposSliderValueChanged(app, event)
            value = app.HorizontalposSlider.Value;
            app.offset_target(1)=round(value); % Accept only integers
            app.EditField.Value = app.offset_target(1); % Change the edit field accordingly
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.offset_target(1) = value;
            app.HorizontalposSlider.Value = value; % Change the value of the corresponding slider
        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            % Create and show another RGB image with a different circle (shifted or with different diameter)
            canvas = zeros(app.desired_dims);
            h=insertShape(canvas,'filled-circle',[app.centre_SLM+app.offset_target round(app.fp_diam_pixels/2)],'ShapeColor',[1,1,1],'Opacity',1);
            app.target = mean(h,3);
            imshow(app.target,'Parent',app.UIAxes);
        end

        % Value changed function: VerticalposSlider
        function VerticalposSliderValueChanged(app, event)
            value = app.VerticalposSlider.Value;
            app.offset_target(2)=round(-value);
            app.EditField_2.Value = round(value); % Change the edit field accordingly
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.offset_target(2)=-value;
            app.VerticalposSlider.Value = value; % Change the value of the corresponding slider
        end

        % Value changed function: DiametermmonSLMSurfaceEditField
        function DiametermmonSLMSurfaceEditFieldValueChanged(app, event)
            % Change the diameter of the circle
            value = app.DiametermmonSLMSurfaceEditField.Value;
            app.fp_diam = value*1e-3;
            app.fp_diam_pixels = round(app.fp_diam/app.SLM_pitch);
        end

        % Value changed function: ScaleTargetEditField
        function ScaleTargetEditFieldValueChanged(app, event)
            % Change the sizing of the target image and its naming in case
            % you want to save it

            value = app.ScaleTargetEditField.Value;
            scale = double(value);

            % Resize the image
            resized_img = imresize(app.selected_img,scale);

            if isequal(app.desired_dims,[1152 1920])
                app.target = resize_to_SLM(app,resized_img);

            elseif isequal(app.desired_dims,[1920 1920])

                if all(size(resized_img)>app.desired_dims)
                    win1 = centerCropWindow2d(size(resized_img),app.desired_dims);
                    app.target = imcrop(resized_img,win1);
                elseif any(size(resized_img)>app.desired_dims)
                    log_arr = size(resized_img)<app.desired_dims;
                    dims_to_pad = (app.desired_dims-size(resized_img)).*log_arr;
                    padded = padarray(resized_img,round(dims_to_pad./2),'both');
                    win1 = centerCropWindow2d(size(padded),app.desired_dims);
                    app.target = imcrop(padded,win1);
                else
                    padded = padarray(resized_img,round((app.desired_dims - size(resized_img))./2),'both');
                    app.target = imresize(padded,app.desired_dims);
                end
                
            else
                app.target = squaring_img(resized_img,app.SLM_dims(1));
            end
            
            
            floored_scale = floor(scale);
            if floored_scale==scale
                new_filename = strcat(app.tgt_filename,'_',num2str(scale));
            else
                number_str = num2str(scale);
                scale_str = strrep(number_str,'.','dot');
                % decimal = scale-floored_scale;
                % power = get_num_decimal_pts(app,decimal);
                new_filename = strcat(app.tgt_filename,'_',scale_str);
            end
            
            % new_scale = value;
            
            % % If the scale has been changed before, account for that in the
            % % filename of the new image
            % if contains(app.tgt_filename, '_scaled_')
            %     scale_before = extractAfter(app.tgt_filename, '_scaled_');
            %     new_scale = value*str2double(scale_before); % Calculate the actual resizing of the image since its selection
            % end
            % 
            % if value ~= 1 
            %     floored_scale = floor(new_scale);
            %     if floored_scale==new_scale % If the scale is an integer, add "_scaled_SCALEVALUE" to the filename
            %         app.tgt_filename = strcat(app.tgt_filename_original,'_scaled_',num2str(new_scale));
            %     else
            %         % If the scale is not an integer, separate the units
            %         % from the decimals and write "_scaled_SCALEdotDECIMALS"
            %         % to the filename. Example: scaling = 3.2 , filename "_scaled_3dot2"
            %         decimal = new_scale-floored_scale;
            %         power = get_num_decimal_pts(app,decimal);
            %         app.tgt_filename = strcat(app.tgt_filename_original,'_scaled_',num2str(floored_scale),'dot',num2str(decimal*(10^power)));
            %     end
            % end

            % Display the new filename for the target
            app.TargetfilenameEditField.Value = new_filename;

            app.unshifted_img = app.target;

            % imshow(app.target, 'Parent',app.UIAxes)
            imagesc(app.UIAxes,app.target)
        end

        % Button pushed function: SavetargetimageButton
        function SavetargetimageButtonPushed(app, event)
            % Save the target image
               
             %If the original filename and the current filename are the same, then the image has not been modified yet
            if strcmp(app.tgt_filename,app.tgt_filename_original)
                disp('Image will NOT be saved! The image has not been changed yet.')
            else
                imwrite(app.target,[app.tgDir,app.tgt_filename,'.bmp'])
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % Get the phase image and display it
           
            value = app.ListBox.Value;
            app.phase = imread(strcat(app.outDir,value)); %Get phase map
            app.phase = double(mean(app.phase,3)); % Convert to double and 2D

            % Resize if needed
            if ~isequal(size(app.phase),app.SLM_dims)
                app.phase = resize_to_SLM(app,app.phase);
            end
            app.phase = app.phase.*(2*pi)./255;
            estimated_phase = app.phase-pi;

            %% Get target

            % Get the amplitude map of incident light on the SLM
            slm_beam_rad = round(app.beam_diameter/(2*app.SLM_pitch)); 
            [slmX, slmY] = meshgrid(-round(app.SLM_dims(2)/2):round(app.SLM_dims(2)/2)-1,-round(app.SLM_dims(1)/2):round(app.SLM_dims(1)/2)-1); 
            slmR = sqrt(slmX.^2 + slmY.^2);
            app.beam_gauss = exp(-2*slmR.^2/slm_beam_rad^2);

            %% Need to find conversion to phase info
            app.target=log(abs(ifft2(app.beam_gauss.*exp(estimated_phase.*1i))));
            
            % Show in figures
            imagesc(app.UIAxes2,app.phase);
            % imshow(app.target,'Parent', app.UIAxes);
            imagesc(app.UIAxes,app.target);
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            % Get the target image and display it

            value = app.ListBox_2.Value;

            % Save filename for later
            app.tgt_filename_original = extractBefore(value, '.');
            
            if app.tgt_filename_original
                app.selected_img = imread(strcat(app.tgDir,value)); %Get target
                app.selected_img = double(mean(app.selected_img,3));
            
                app.tgt_filename = app.tgt_filename_original;
                app.TargetfilenameEditField.Value = app.tgt_filename;
                
                % resize image according to the desired target size
                if isequal(app.desired_dims,[1152 1152])
                    app.target = squaring_img(app.selected_img,app.SLM_dims(1));
                elseif isequal(app.desired_dims,[1152 1920])
                    app.target = resize_to_SLM(app,app.selected_img);
                else
                    padded = padarray(app.selected_img,round((app.desired_dims - size(app.selected_img))./2),'both');
                    app.target = imresize(padded,app.desired_dims);
                end
                 
                app.unshifted_img = app.target; % Save how the image looks like unshifted
                
                %Start at scale = 1
                app.ScaleTargetEditField.Value = 1;
    
                % imshow(app.target,'Parent', app.UIAxes);
    
                imagesc(app.UIAxes,app.target);
            end
            
        end

        % Value changed function: VerticalposSlider_2
        function VerticalposSlider_2ValueChanged(app, event)
            value = app.VerticalposSlider_2.Value;
            app.offset_target(2)=round(-value); % Use negative value because 
            % the shifting function takes negative offset values as the vertical positive direction
            app.EditField_5.Value = round(value); % Change the value of the edit field
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.offset_target(2)=-value; 
            app.VerticalposSlider_2.Value = value; % Change the value of the corresponding slider
        end

        % Value changed function: HorizontalposSlider_2
        function HorizontalposSlider_2ValueChanged(app, event)
            value = app.HorizontalposSlider_2.Value;
            app.offset_target(1)=round(value);
            app.EditField_4.Value = app.offset_target(1); % Change the value of the edit field
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.offset_target(1) = value;
            app.HorizontalposSlider_2.Value = value; % Change the value of the corresponding slider
        end

        % Button pushed function: ApplyoffsetsButton
        function ApplyoffsetsButtonPushed(app, event)
            % Shift the unshifted target image 
            shifted = circshift(app.unshifted_img,flip(app.offset_target)); 
            
            % Because circshift makes the image repeat itself if shifted too much,
            % we need to fill it with zeros.
            % So, if the image is shiftet to the left, fill the right hand
            % side with zeros
            if app.offset_target(1) <0 
                shifted(:,(end+app.offset_target(1)):end)= 0;
            else
                % else fill it with zeros on the left hand side
                shifted(:,(1:app.offset_target(1)))= 0;
            end
            
            % same as before but with up and down
            if app.offset_target(2) <0 
                shifted((end+app.offset_target(2)):end,:)= 0;
            else
                shifted((1:app.offset_target(2)),:)= 0;
            end

            % Display result
            imagesc(app.UIAxes,shifted);

            app.target = shifted;
        end

        % Button pushed function: AddcircleButton
        function AddcircleButtonPushed(app, event)
            % Add a circle to the target image

            % Get the current circles that are being displayed
            items = app.CircleselectionDropDown.Items;
            num = length(items);


            if ~num % if this is the first time we add a circle, we make the
                % first element become 'Circle 1'
                items{1} = 'Circle 1';
                    
                % Set default offset and diameter to the circle
                app.offset_matrix = [0,0];
                app.diam_matrix = app.DiametermmonSLMSurfaceEditField_2.Value*1e-3;
                app.opacity_matrix = 1; % Assume initial opacity is 1

            elseif isempty(items{1})
                % When we delete all circles, the items list will appear
                % empty. In that case we need to add a new element and call
                % it 'Circle 1'
                items{1} = 'Circle 1';
                num=0;
                
                % Set default offset and diameter to the circle
                app.offset_matrix = [0,0];
                app.diam_matrix = app.DiametermmonSLMSurfaceEditField_2.Value*1e-3;
                app.opacity_matrix = 1;
                
            else
                % For any other length of the list of circles, we will add
                % a new circle to the list
                items{end+1} = char(strcat("Circle ",num2str(num+1)));

                app.offset_matrix = [app.offset_matrix ; 0 0];
                app.diam_matrix = [app.diam_matrix ; app.DiametermmonSLMSurfaceEditField_2.Value*1e-3];
                app.opacity_matrix = [app.opacity_matrix 1];
            end
            
            % Calculate the diameter of each circle in number of pixels
            app.diam_pixels_matrix = [round(app.diam_matrix./app.SLM_pitch)];
            
            % Display all the circles
            app = draw_circles(app);
            
            % Make sure the dropdown list displays the new circle
            app.CircleselectionDropDown.Items = items;
            app.CircleselectionDropDown.ValueIndex = num+1;

            % Display the info of the last circle added
            disp_circ_info(app,num+1)
            
        end

        % Button pushed function: RemovelastcircleButton
        function RemovelastcircleButtonPushed(app, event)

            items = app.CircleselectionDropDown.Items;
            num = length(items);

            % We only delete circles if there are any to delete
            if num
                if num>1
                    % Change the dropdown selection
                    app.CircleselectionDropDown.ValueIndex = num-1;
                    disp_circ_info(app,num-1)
                end
                % Delete the circle and its info
                app.CircleselectionDropDown.Items(num) = [];
                app.offset_matrix(num,:) = [];
                app.diam_matrix(num) = [];
                app.diam_pixels_matrix(num) = [];
                app.opacity_matrix(num) = [];
            end

            % Display all the other circles
            app = draw_circles(app);
            
        end

        % Value changed function: VerticalposSlider_3
        function VerticalposSlider_3ValueChanged(app, event)
            value = app.VerticalposSlider_3.Value;
            idx = app.CircleselectionDropDown.ValueIndex;
            app.offset_matrix(idx,2)=round(-value); % Use negative value because 
            % the shifting function takes negative offset values as the vertical positive direction
            app.EditField_7.Value = round(value); % Change the value of the edit field

            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: HorizontalposSlider_3
        function HorizontalposSlider_3ValueChanged(app, event)
            value = app.HorizontalposSlider_3.Value;
            idx = app.CircleselectionDropDown.ValueIndex;
            app.offset_matrix(idx,1)=round(value);
            app.EditField_6.Value = app.offset_matrix(idx,1); % Change the value of the edit field


            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            idx = app.CircleselectionDropDown.ValueIndex;
            app.offset_matrix(idx,2)=-value; 
            app.VerticalposSlider_3.Value = value;


            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            idx = app.CircleselectionDropDown.ValueIndex;
            app.offset_matrix(idx,1) = value;
            app.HorizontalposSlider_3.Value = value; % Change the value of the corresponding slider


            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Button pushed function: ApplychangesButton_2
        function ApplychangesButton_2Pushed(app, event)
            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: DiametermmonSLMSurfaceEditField_2
        function DiametermmonSLMSurfaceEditField_2ValueChanged(app, event)
            value = app.DiametermmonSLMSurfaceEditField_2.Value*1e-3;
            idx = app.CircleselectionDropDown.ValueIndex;
            app.diam_matrix(idx) = value;
            app.diam_pixels_matrix(idx) = round(value/app.SLM_pitch); 


            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: CircleselectionDropDown
        function CircleselectionDropDownValueChanged(app, event)
            idx = app.CircleselectionDropDown.ValueIndex;
            disp_circ_info(app,idx);

        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            %Previewing images for the target
            
            % Get the name of the image
            value = app.ListBox_3.Value;
            name = extractBefore(app.ListBox_3.Value, '.');

            app.NoselectedimageLabel.Visible = false;
           
            if ~isempty(name)
                img = imread(strcat(app.tgDir,value)); %Get the target
                
                app.poss_img = double(mean(img,3));
                
                % Display it on the preview figure
                dims = size(app.poss_img);
                app.UIAxes_2.PlotBoxAspectRatio = [dims(2) dims(1) 1];
                imagesc(app.UIAxes_2,app.poss_img);
            end
                        
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            % Add image to target canvas

            % Get the name of the image
            name = char(extractBefore(app.ListBox_3.Value, '.'));

            items = app.imageSelectionDropDown.Items;
            num = length(items);

            if ~isempty(name) % Check that we are using an image with a name 
                % (and not the '.' and '..' directories)
                
                if ~num % if this is the first time we add an image to the target,
                    % num will equal 0. We will add the first element with
                    % the name of the image.
                    items{1} = name;

                    % set the default info about the image                    
                    app.offset_mat_img = [0,0];
                    app.scale_mat_img = 1;
                    app.img_info{1} = app.poss_img;
                    app.img_info_original{1} = app.poss_img;

                elseif isempty(items{1}) % if all images have been previously removed 
                    % and we want to add one we set num to 0 and add the
                    % first element to the dropdown list
                    items{1} = name;
                    num=0;
                    
                    % set the default info about the image              
                    app.offset_mat_img = [0,0];
                    app.scale_mat_img = 1;
                    app.img_info{1} = app.poss_img;
                    app.img_info_original{1} = app.poss_img;

                else
                    % For any other length of the list of images in the
                    % target, we add them at the and of the dropdown list
                    items{end+1} = name;

                    app.offset_mat_img = [app.offset_mat_img ; 0 0];
                    app.scale_mat_img = [app.scale_mat_img ; 1];
                    app.img_info{end+1} = app.poss_img;
                    app.img_info_original{end+1} = app.poss_img;
                end
                
                % Change the droplist selection to our current image
                app.imageSelectionDropDown.Items = items;
                app.imageSelectionDropDown.ValueIndex = num+1;
                
                % Display the current image's info
                disp_img_info(app,num+1);
                
                % Display all images
                show_all_imgs(app);

            else
                app.NoselectedimageLabel.Visible = true;
            end

            
        end

        % Value changed function: VerticalposSlider_4
        function VerticalposSlider_4ValueChanged(app, event)
            value = app.VerticalposSlider_4.Value;
            idx = app.imageSelectionDropDown.ValueIndex;
            app.offset_mat_img(idx,2)=round(-value); % Use negative value because 
            % the shifting function takes negative offset values as the vertical positive direction
            app.EditField_9.Value = round(value); % Change the value of the edit field
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            idx = app.imageSelectionDropDown.ValueIndex;
            app.offset_mat_img(idx,2)=-value; 
            app.VerticalposSlider_4.Value = value;
        end

        % Value changed function: HorizontalposSlider_4
        function HorizontalposSlider_4ValueChanged(app, event)
            value = app.HorizontalposSlider_4.Value;
            idx = app.imageSelectionDropDown.ValueIndex;
            app.offset_mat_img(idx,1)=round(value);
            app.EditField_8.Value = app.offset_mat_img(idx,1); % Change the value of the edit field
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            idx = app.imageSelectionDropDown.ValueIndex;
            app.offset_mat_img(idx,1)=value; 
            app.HorizontalposSlider_4.Value = value;
        end

        % Button pushed function: ApplychangesButton_3
        function ApplychangesButton_3Pushed(app, event)
            app=show_all_imgs(app);
            idx = app.imageSelectionDropDown.ValueIndex;
            disp_img_info(app,idx);
        end

        % Button pushed function: RemoveselectedImageButton
        function RemoveselectedImageButtonPushed(app, event)
            % Remove the selected image

            % Get the current images and the selected one
            items = app.imageSelectionDropDown.Items;
            idx = app.imageSelectionDropDown.ValueIndex;
            num = length(items);

            % We only delete images if there are any to remove
            if num
                if idx>1
                    % change the displayed image on the dropdownlist
                    app.imageSelectionDropDown.ValueIndex = idx-1;
                end

                % Delete the info of the selected image
                app.imageSelectionDropDown.Items(idx) = [];
                app.offset_mat_img(idx,:) = [];
                app.scale_mat_img(idx) = [];
                app.img_info(idx) = [];
                app.img_info_original = [];
            end
            
            %Diplay all the other images
            app = show_all_imgs(app);
            
        end

        % Value changed function: imageSelectionDropDown
        function imageSelectionDropDownValueChanged(app, event)
            value = app.imageSelectionDropDown.ValueIndex;
            disp_img_info(app,value)
        end

        % Value changed function: ScaleimgEditField
        function ScaleimgEditFieldValueChanged(app, event)
            scale = app.ScaleimgEditField.Value;
            idx = app.imageSelectionDropDown.ValueIndex;
            app.scale_mat_img(idx)=scale;
            old_img = app.img_info_original{idx};
            app.img_info{idx}=imresize(old_img,scale);
        end

        % Value changed function: WavelegthnmDropDown
        function WavelegthnmDropDownValueChanged(app, event)
            value = app.WavelegthnmDropDown.Value;
            app.SLM_wavelength = value;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.filesList = dir(app.tgDir);
            app.ListBox_2.Items = {app.filesList.name}';
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            app.filesList = dir(app.outDir);
            app.ListBox.Items = {app.filesList.name}';
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            app.filesList = dir(app.tgDir);
            app.ListBox_3.Items = {app.filesList.name}';
        end

        % Value changed function: TargetfilenameEditField
        function TargetfilenameEditFieldValueChanged(app, event)
            value = app.TargetfilenameEditField.Value;
            app.tgt_filename = value;
        end

        % Callback function
        function SquaretargetSwitchValueChanged(app, event)
            switch_to_square_target(app);
        end

        % Value changed function: TargetSizepixelsDropDown
        function TargetSizepixelsDropDownValueChanged(app, event)
            switch_to_square_target(app);
        end

        % Value changed function: OpacitySlider
        function OpacitySliderValueChanged(app, event)
            value = app.OpacitySlider.Value;
            value = round(value,2);
            idx = app.CircleselectionDropDown.ValueIndex;
            app.EditField_10.Value = value;
            app.OpacitySlider.Value = value;

            app.opacity_matrix(idx)=value;

            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            idx = app.CircleselectionDropDown.ValueIndex;
            app.OpacitySlider.Value = value;

            app.opacity_matrix(idx)=value;

            app = draw_circles(app);
            disp_circ_info(app,app.CircleselectionDropDown.ValueIndex);
        end

        % Value changed function: ConnecttoSLMrealtimeSwitch
        function ConnecttoSLMrealtimeSwitchValueChanged(app, event)
            app.update_SLM = app.ConnecttoSLMrealtimeSwitch.ValueIndex - 1;
            
        end

        % Button pushed function: StartexpButton
        function StartexpButtonPushed(app, event)
            % Get range of explorable grid
            hor_range = app.HorrangeSlider.Value + app.centre_SLM(1);
            ver_range = app.VerrangeSlider.Value + app.centre_SLM(2);
            % hor_range = app.centre_SLM(1).*ones(1,2)+app.HorrangeSlider.Limits - [0 1];
            % ver_range = app.centre_SLM(2).*ones(1,2)+app.VerrangeSlider.Limits - [0 1];

            % disp(hor_range)
            % disp(ver_range)
            mkdir([app.tgDir,'fibres\',app.exp_name,'\'])
            mkdir([app.outDir,'fibres\',app.exp_name,'\'])
            
            step = app.SizeofsquarepixsEditField.Value;

            % For each area that is explored:
            for a = hor_range(1):step:hor_range(2)
                for b = ver_range(1):step:ver_range(2)
                    % get size of phase pattern
                    canvas = zeros(app.desired_dims);
                    canvas(a:a+step-1,b:b+step-1) = 1;

                    % create images for exp. and save in \\img\\out\\name_of_folder
                    imagesc(app.UIAxes,canvas);
                    app.target = canvas;
                    
                    filename_tgt = ['tgt_',num2str(a-app.centre_SLM(1)),'_',num2str(a-app.centre_SLM(1)),'_step_',num2str(step),'.bmp'];
                    filename_phase = ['phase_',num2str(a-app.centre_SLM(1)),'_',num2str(a-app.centre_SLM(1)),'_step_',num2str(step),'.bmp']; 

                    imwrite(app.target,[app.tgDir,'fibres\',app.exp_name,'\',filename_tgt])

                    calc_GS_routine(app);

                    
                    img_to_save = uint8((app.phase).*255./(2*pi));
                    imwrite(img_to_save,[app.outDir,'fibres\',app.exp_name,'\',filename_phase])
                end
            end


            % set save_dir as
            saving_dir =  [app.fibre_dir,app.exp_name];
            mkdir(saving_dir)


            % Get the names of all the phase maps
            phase_list = dir([app.outDir,'fibres\',app.exp_name,'\']);
            phase_list = {phase_list.name};
            phase_list = phase_list(3:end);

            time_for_cam = app.time_on;
            
            for a = 1:length(phase_list)
                file_sent = string(phase_list(a));
                app.phase = imread(strcat(app.outDir,'fibres\',app.exp_name,'\',file_sent)); %Get phase map
                app.phase = double(mean(app.phase,3)); % Convert to double and 2D
                app.phase = app.phase.*(2*pi)./255;

                cropped_filename = erase(file_sent,'.bmp');

                python_saving_dir = strcat(saving_dir,'//',cropped_filename);
                python_saving_dir = strrep(python_saving_dir,'\','//');
                
                % % Send info to SLM
                send_info_to_SLM(app)
                
                %Connect to camera and save files
                pyrunfile('camera_code/python/main_script.py',a = time_for_cam, save_dir = python_saving_dir);
            end
            
            
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.VerrangeSlider.Value(2) = value;
            app.EditField_13.Limits(2) = value;
        
            
        end

        % Value changed function: HorrangeSlider
        function HorrangeSliderValueChanged(app, event)
            value = app.HorrangeSlider.Value;
            round_val = round(value);
            app.HorrangeSlider.Value = round_val;
            app.EditField_12.Value = round_val(1);
            app.EditField_14.Value = round_val(2);
            app.EditField_12.Limits(2) = round_val(2);
            app.EditField_14.Limits(1) = round_val(1);
            
            
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            app.VerrangeSlider.Value(1) = value;
            app.EditField_11.Limits(1) = value;
        
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            app.HorrangeSlider.Value(1) = value;
            app.EditField_14.Limits(1) = value;
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            app.HorrangeSlider.Value(2) = value;
            app.EditField_12.Limits(2) = value;
        end

        % Value changed function: VerrangeSlider
        function VerrangeSliderValueChanged(app, event)
            value = app.VerrangeSlider.Value;
            round_val = round(value);
            app.VerrangeSlider.Value = round_val;
            app.EditField_13.Value = round_val(1);
            app.EditField_11.Value = round_val(2);
            app.EditField_13.Limits(2) = round_val(2);
            app.EditField_11.Limits(1) = round_val(1);
        end

        % Value changed function: TimeperacqsEditField
        function TimeperacqsEditFieldValueChanged(app, event)
            value = app.TimeperacqsEditField.Value;
            app.time_on = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Target Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Gerchberg-Saxton Routine Output')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Focal plane estimate')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.EasyHoloAppLabel = uilabel(app.GridLayout2);
            app.EasyHoloAppLabel.Text = 'Easy HoloApp';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step 1';
            app.ImageSelectionListBoxLabel = uilabel(app.Step1Tab);
            app.ImageSelectionListBoxLabel.Text = '1. Image Selection';
            app.ListBox_2 = uilistbox(app.Step1Tab);
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.HorizontalposSliderLabel_2 = uilabel(app.Step1Tab);
            app.HorizontalposSliderLabel_2.Text = 'Horizontal pos';
            app.HorizontalposSlider_2 = uislider(app.Step1Tab);
            app.HorizontalposSlider_2.Limits = [-1152 1152];
            app.HorizontalposSlider_2.ValueChangedFcn = createCallbackFcn(app, @HorizontalposSlider_2ValueChanged, true);
            app.EditField_4 = uieditfield(app.Step1Tab, 'numeric');
            app.EditField_4.Limits = [-1152 1152];
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.VerticalposSlider_2Label = uilabel(app.Step1Tab);
            app.VerticalposSlider_2Label.Text = 'Vertical pos';
            app.VerticalposSlider_2 = uislider(app.Step1Tab);
            app.VerticalposSlider_2.Limits = [-1152 1152];
            app.VerticalposSlider_2.ValueChangedFcn = createCallbackFcn(app, @VerticalposSlider_2ValueChanged, true);
            app.EditField_5 = uieditfield(app.Step1Tab, 'numeric');
            app.EditField_5.Limits = [-1152 1152];
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.ChooseoneofthefollowingtargetsLabel = uilabel(app.Step1Tab);
            app.ChooseoneofthefollowingtargetsLabel.Text = 'Choose one of the following targets:';
            app.ScaleTargetEditFieldLabel = uilabel(app.Step1Tab);
            app.ScaleTargetEditFieldLabel.Text = 'Scale Target';
            app.ScaleTargetEditField = uieditfield(app.Step1Tab, 'numeric');
            app.ScaleTargetEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleTargetEditFieldValueChanged, true);
            app.ScaleTargetEditField.Value = 1;
            app.RefreshButton = uibutton(app.Step1Tab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ApplyoffsetsButton = uibutton(app.Step1Tab, 'push');
            app.ApplyoffsetsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyoffsetsButtonPushed, true);
            app.ApplyoffsetsButton.Text = 'Apply offsets';
            app.Step23Tab = uitab(app.TabGroup);
            app.Step23Tab.Title = 'Step 2+3';
            app.GerchbergSaxtonRoutineLabel = uilabel(app.Step23Tab);
            app.GerchbergSaxtonRoutineLabel.Text = '2. Gerchberg-Saxton Routine';
            app.iterationsforGSEditFieldLabel = uilabel(app.Step23Tab);
            app.iterationsforGSEditFieldLabel.Text = '# iterations for GS';
            app.iterationsforGSEditField = uieditfield(app.Step23Tab, 'numeric');
            app.iterationsforGSEditField.ValueChangedFcn = createCallbackFcn(app, @iterationsforGSEditFieldValueChanged, true);
            app.iterationsforGSEditField.Value = 30;
            app.BeamdiametermmEditFieldLabel = uilabel(app.Step23Tab);
            app.BeamdiametermmEditFieldLabel.Text = 'Beam diameter (mm)';
            app.BeamdiametermmEditField = uieditfield(app.Step23Tab, 'numeric');
            app.BeamdiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @BeamdiametermmEditFieldValueChanged, true);
            app.BeamdiametermmEditField.Value = 20;
            app.ApplyGSroutineButton = uibutton(app.Step23Tab, 'push');
            app.ApplyGSroutineButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGSroutineButtonPushed, true);
            app.ApplyGSroutineButton.Text = 'Apply GS routine';
            app.OraddalreadygeneratedphasemapLabel = uilabel(app.Step23Tab);
            app.OraddalreadygeneratedphasemapLabel.Text = 'Or add already-generated phase map';
            app.ListBox = uilistbox(app.Step23Tab);
            app.ListBox.Items = {'Phase_map_1', 'Phase_map_2', 'Phase_map_3', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Phase_map_1';
            app.RefreshButton_2 = uibutton(app.Step23Tab, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.WavelegthnmDropDownLabel = uilabel(app.Step23Tab);
            app.WavelegthnmDropDownLabel.Text = 'Wavelegth (nm)';
            app.WavelegthnmDropDown = uidropdown(app.Step23Tab);
            app.WavelegthnmDropDown.Items = {'532', '1064'};
            app.WavelegthnmDropDown.ValueChangedFcn = createCallbackFcn(app, @WavelegthnmDropDownValueChanged, true);
            app.WavelegthnmDropDown.Value = '1064';
            app.ApplytoSLMButton = uibutton(app.Step23Tab, 'push');
            app.ApplytoSLMButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoSLMButtonPushed, true);
            app.ApplytoSLMButton.Text = 'Apply to SLM';
            app.ApplytoSLMLabel = uilabel(app.Step23Tab);
            app.ApplytoSLMLabel.Text = '3. Apply to SLM';
            app.ExtramovingcircleTab = uitab(app.TabGroup);
            app.ExtramovingcircleTab.Title = 'Extra: moving circle';
            app.GridLayout3 = uigridlayout(app.ExtramovingcircleTab);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '     ';
            app.StartwithacircleButton = uibutton(app.GridLayout3, 'push');
            app.StartwithacircleButton.ButtonPushedFcn = createCallbackFcn(app, @StartwithacircleButtonPushed, true);
            app.StartwithacircleButton.Text = 'Start with a circle';
            app.ApplychangesButton = uibutton(app.GridLayout3, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.EditField = uieditfield(app.GridLayout3, 'numeric');
            app.EditField.Limits = [-575 575];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_2.Limits = [-576 575];
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.AssumedSLMcentre961577Label = uilabel(app.GridLayout3);
            app.AssumedSLMcentre961577Label.Text = 'Assumed SLM centre: 961,577';
            app.CreateacirculartargetofvariablediameterandpositionLabel = uilabel(app.GridLayout3);
            app.CreateacirculartargetofvariablediameterandpositionLabel.Text = 'Create a circular target of variable diameter and position';
            app.InfoLabel = uilabel(app.GridLayout3);
            app.InfoLabel.Text = 'Info';
            app.HorizontalposSliderLabel = uilabel(app.GridLayout3);
            app.HorizontalposSliderLabel.Text = 'Horizontal pos';
            app.HorizontalposSlider = uislider(app.GridLayout3);
            app.HorizontalposSlider.Limits = [-575 575];
            app.HorizontalposSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalposSliderValueChanged, true);
            app.VerticalposSliderLabel = uilabel(app.GridLayout3);
            app.VerticalposSliderLabel.Text = 'Vertical pos';
            app.VerticalposSlider = uislider(app.GridLayout3);
            app.VerticalposSlider.Limits = [-576 575];
            app.VerticalposSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalposSliderValueChanged, true);
            app.DiametermmonSLMSurfaceEditFieldLabel = uilabel(app.GridLayout3);
            app.DiametermmonSLMSurfaceEditFieldLabel.Text = 'Diameter (mm) - on SLM Surface';
            app.DiametermmonSLMSurfaceEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DiametermmonSLMSurfaceEditField.ValueChangedFcn = createCallbackFcn(app, @DiametermmonSLMSurfaceEditFieldValueChanged, true);
            app.DiametermmonSLMSurfaceEditField.Value = 1;
            app.ExtramovemanycirclesTab = uitab(app.TabGroup);
            app.ExtramovemanycirclesTab.Title = 'Extra: move many circles';
            app.GridLayout4 = uigridlayout(app.ExtramovemanycirclesTab);
            app.AddcircleButton = uibutton(app.GridLayout4, 'push');
            app.AddcircleButton.ButtonPushedFcn = createCallbackFcn(app, @AddcircleButtonPushed, true);
            app.AddcircleButton.Text = 'Add circle';
            app.HorizontalposSliderLabel_3 = uilabel(app.GridLayout4);
            app.HorizontalposSliderLabel_3.Text = 'Horizontal pos';
            app.HorizontalposSlider_3 = uislider(app.GridLayout4);
            app.HorizontalposSlider_3.Limits = [-575 575];
            app.HorizontalposSlider_3.ValueChangedFcn = createCallbackFcn(app, @HorizontalposSlider_3ValueChanged, true);
            app.VerticalposSliderLabel_2 = uilabel(app.GridLayout4);
            app.VerticalposSliderLabel_2.Text = 'Vertical pos';
            app.VerticalposSlider_3 = uislider(app.GridLayout4);
            app.VerticalposSlider_3.Limits = [-576 575];
            app.VerticalposSlider_3.ValueChangedFcn = createCallbackFcn(app, @VerticalposSlider_3ValueChanged, true);
            app.DiametermmonSLMSurfaceEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.DiametermmonSLMSurfaceEditFieldLabel_2.Text = 'Diameter (mm) - on SLM Surface';
            app.DiametermmonSLMSurfaceEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.DiametermmonSLMSurfaceEditField_2.ValueChangedFcn = createCallbackFcn(app, @DiametermmonSLMSurfaceEditField_2ValueChanged, true);
            app.DiametermmonSLMSurfaceEditField_2.Value = 1;
            app.EditField_6 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_6.Limits = [-575 575];
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_7.Limits = [-576 575];
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.CreatemultiplecirclesandmovethemaroundLabel = uilabel(app.GridLayout4);
            app.CreatemultiplecirclesandmovethemaroundLabel.Text = 'Create multiple circles and move them around';
            app.RemovelastcircleButton = uibutton(app.GridLayout4, 'push');
            app.RemovelastcircleButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastcircleButtonPushed, true);
            app.RemovelastcircleButton.Text = 'Remove last circle';
            app.ApplychangesButton_2 = uibutton(app.GridLayout4, 'push');
            app.ApplychangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButton_2Pushed, true);
            app.ApplychangesButton_2.Visible = 'off';
            app.ApplychangesButton_2.Text = 'Apply changes';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '     ';
            app.PositionhvLabel_2 = uilabel(app.GridLayout4);
            app.PositionhvLabel_2.Text = {'Position [h,v]:'; ''};
            app.InfoaboutselectedcircleLabel = uilabel(app.GridLayout4);
            app.InfoaboutselectedcircleLabel.Text = 'Info about selected circle';
            app.xxxyyyLabel = uilabel(app.GridLayout4);
            app.xxxyyyLabel.Text = '[xxx,yyy]';
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '1';
            app.CircleselectionDropDownLabel = uilabel(app.GridLayout4);
            app.CircleselectionDropDownLabel.Text = 'Circle selection';
            app.CircleselectionDropDown = uidropdown(app.GridLayout4);
            app.CircleselectionDropDown.Items = {''};
            app.CircleselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CircleselectionDropDownValueChanged, true);
            app.CircleselectionDropDown.Value = '';
            app.DiametermmLabel = uilabel(app.GridLayout4);
            app.DiametermmLabel.Text = 'Diameter (mm):';
            app.OpacitySliderLabel = uilabel(app.GridLayout4);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.GridLayout4);
            app.OpacitySlider.Limits = [0 1];
            app.OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @OpacitySliderValueChanged, true);
            app.OpacitySlider.Value = 0.300462962962963;
            app.EditField_10 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.ConnecttoSLMrealtimeSwitchLabel = uilabel(app.GridLayout4);
            app.ConnecttoSLMrealtimeSwitchLabel.Text = 'Connect to SLM real time';
            app.ConnecttoSLMrealtimeSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.ConnecttoSLMrealtimeSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnecttoSLMrealtimeSwitchValueChanged, true);
            app.ExtraAddmultiplepicturesTab = uitab(app.TabGroup);
            app.ExtraAddmultiplepicturesTab.Title = 'Extra: Add multiple pictures';
            app.UIAxes_2 = uiaxes(app.ExtraAddmultiplepicturesTab);
            title(app.UIAxes_2, 'Preview')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ListBox_3 = uilistbox(app.ExtraAddmultiplepicturesTab);
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ChooseoneofthefollowingtargetsLabel_2 = uilabel(app.ExtraAddmultiplepicturesTab);
            app.ChooseoneofthefollowingtargetsLabel_2.Text = 'Choose one of the following targets:';
            app.AddImageButton = uibutton(app.ExtraAddmultiplepicturesTab, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = 'Add Image';
            app.SelectimagesformultipletargetscontinuesinnexttabLabel = uilabel(app.ExtraAddmultiplepicturesTab);
            app.SelectimagesformultipletargetscontinuesinnexttabLabel.Text = 'Select images for multiple targets (continues in next tab)';
            app.NoselectedimageLabel = uilabel(app.ExtraAddmultiplepicturesTab);
            app.NoselectedimageLabel.Visible = 'off';
            app.NoselectedimageLabel.Text = 'No selected image!';
            app.RefreshButton_3 = uibutton(app.ExtraAddmultiplepicturesTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.ExtramovingpicturesTab = uitab(app.TabGroup);
            app.ExtramovingpicturesTab.Title = 'Extra: moving pictures';
            app.GridLayout = uigridlayout(app.ExtramovingpicturesTab);
            app.HorizontalposSliderLabel_4 = uilabel(app.GridLayout);
            app.HorizontalposSliderLabel_4.Text = 'Horizontal pos';
            app.HorizontalposSlider_4 = uislider(app.GridLayout);
            app.HorizontalposSlider_4.Limits = [-1152 1152];
            app.HorizontalposSlider_4.ValueChangedFcn = createCallbackFcn(app, @HorizontalposSlider_4ValueChanged, true);
            app.VerticalposSlider_4 = uislider(app.GridLayout);
            app.VerticalposSlider_4.Limits = [-1152 1152];
            app.VerticalposSlider_4.ValueChangedFcn = createCallbackFcn(app, @VerticalposSlider_4ValueChanged, true);
            app.EditField_8 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_8.Limits = [-1152 1152];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_9 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_9.Limits = [-1152 1152];
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.MoveandscaleanyselectedimageLabel = uilabel(app.GridLayout);
            app.MoveandscaleanyselectedimageLabel.Text = 'Move and scale any selected image';
            app.ApplychangesButton_3 = uibutton(app.GridLayout, 'push');
            app.ApplychangesButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButton_3Pushed, true);
            app.ApplychangesButton_3.Text = 'Apply changes';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '     ';
            app.PositionhvLabel = uilabel(app.GridLayout);
            app.PositionhvLabel.Text = {'   Position [h,v]:'; ''};
            app.InfoaboutselectedimageLabel = uilabel(app.GridLayout);
            app.InfoaboutselectedimageLabel.Text = 'Info about selected image';
            app.xxxyyyLabel_2 = uilabel(app.GridLayout);
            app.xxxyyyLabel_2.Text = '[xxx,yyy]';
            app.RemoveselectedImageButton = uibutton(app.GridLayout, 'push');
            app.RemoveselectedImageButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedImageButtonPushed, true);
            app.RemoveselectedImageButton.Text = 'Remove selected Image';
            app.ScalingLabel = uilabel(app.GridLayout);
            app.ScalingLabel.Text = '   Scaling:';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '1';
            app.HorizontalposSliderLabel_5 = uilabel(app.GridLayout);
            app.HorizontalposSliderLabel_5.Text = 'Vertical pos';
            app.imageSelectionDropDownLabel = uilabel(app.GridLayout);
            app.imageSelectionDropDownLabel.Text = 'image Selection';
            app.imageSelectionDropDown = uidropdown(app.GridLayout);
            app.imageSelectionDropDown.Items = {''};
            app.imageSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @imageSelectionDropDownValueChanged, true);
            app.imageSelectionDropDown.Value = '';
            app.DiametermmonSLMSurfaceEditFieldLabel_3 = uilabel(app.GridLayout);
            app.DiametermmonSLMSurfaceEditFieldLabel_3.Text = 'Scaling';
            app.ScaleimgEditField = uieditfield(app.GridLayout, 'numeric');
            app.ScaleimgEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleimgEditFieldValueChanged, true);
            app.ScaleimgEditField.Value = 1;
            app.MappingfibresTab = uitab(app.TabGroup);
            app.MappingfibresTab.Title = 'Mapping fibres';
            app.StartexpButton = uibutton(app.MappingfibresTab, 'push');
            app.StartexpButton.ButtonPushedFcn = createCallbackFcn(app, @StartexpButtonPushed, true);
            app.StartexpButton.Text = 'Start exp';
            app.TimeperacqsEditFieldLabel = uilabel(app.MappingfibresTab);
            app.TimeperacqsEditFieldLabel.Text = 'Time per  acq. (s)';
            app.TimeperacqsEditField = uieditfield(app.MappingfibresTab, 'numeric');
            app.TimeperacqsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeperacqsEditFieldValueChanged, true);
            app.TimeperacqsEditField.Value = 0.333333333333333;
            app.FoldernameEditFieldLabel = uilabel(app.MappingfibresTab);
            app.FoldernameEditFieldLabel.Text = 'Folder   name';
            app.FoldernameEditField = uieditfield(app.MappingfibresTab, 'text');
            app.FoldernameEditField.Value = 'fibre_data';
            app.VerrangeSliderLabel = uilabel(app.MappingfibresTab);
            app.VerrangeSliderLabel.Text = 'Ver range';
            app.VerrangeSlider = uislider(app.MappingfibresTab, 'range');
            app.VerrangeSlider.Limits = [1 1152];
            app.VerrangeSlider.ValueChangedFcn = createCallbackFcn(app, @VerrangeSliderValueChanged, true);
            app.VerrangeSlider.Value = [1 100];
            app.HorrangeSliderLabel = uilabel(app.MappingfibresTab);
            app.HorrangeSliderLabel.Text = 'Hor range';
            app.HorrangeSlider = uislider(app.MappingfibresTab, 'range');
            app.HorrangeSlider.Limits = [1 1920];
            app.HorrangeSlider.ValueChangedFcn = createCallbackFcn(app, @HorrangeSliderValueChanged, true);
            app.HorrangeSlider.Value = [1 100];
            app.EditField_11 = uieditfield(app.MappingfibresTab, 'numeric');
            app.EditField_11.Limits = [-Inf 1152];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.MappingfibresTab, 'numeric');
            app.EditField_12.Limits = [1 1920];
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_12.Value = 1;
            app.SizeofsquarepixsEditFieldLabel = uilabel(app.MappingfibresTab);
            app.SizeofsquarepixsEditFieldLabel.Text = 'Size of square (pixs)';
            app.SizeofsquarepixsEditField = uieditfield(app.MappingfibresTab, 'numeric');
            app.SizeofsquarepixsEditField.Value = 5;
            app.EditField_13 = uieditfield(app.MappingfibresTab, 'numeric');
            app.EditField_13.Limits = [1 Inf];
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Value = 1;
            app.EditField_14 = uieditfield(app.MappingfibresTab, 'numeric');
            app.EditField_14.Limits = [1 1920];
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_14.Value = 1;
            app.SavePhasemapButton = uibutton(app.GridLayout2, 'push');
            app.SavePhasemapButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhasemapButtonPushed, true);
            app.SavePhasemapButton.Text = 'Save Phase map';
            app.SavetargetimageButton = uibutton(app.GridLayout2, 'push');
            app.SavetargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @SavetargetimageButtonPushed, true);
            app.SavetargetimageButton.Text = 'Save target image';
            app.PhasefilenameEditFieldLabel = uilabel(app.GridLayout2);
            app.PhasefilenameEditFieldLabel.Text = 'Phase filename';
            app.PhasefilenameEditField = uieditfield(app.GridLayout2, 'text');
            app.PhasefilenameEditField.ValueChangedFcn = createCallbackFcn(app, @PhasefilenameEditFieldValueChanged, true);
            app.PhasefilenameEditField.Value = 'test_file';
            app.TargetfilenameLabel = uilabel(app.GridLayout2);
            app.TargetfilenameLabel.Text = 'Target filename         ';
            app.TargetfilenameEditField = uieditfield(app.GridLayout2, 'text');
            app.TargetfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TargetfilenameEditFieldValueChanged, true);
            app.TargetSizepixelsDropDownLabel = uilabel(app.GridLayout2);
            app.TargetSizepixelsDropDownLabel.Text = 'Target Size (pixels)';
            app.TargetSizepixelsDropDown = uidropdown(app.GridLayout2);
            app.TargetSizepixelsDropDown.Items = {'1920x1920', '1152x1152', '1920x1152'};
            app.TargetSizepixelsDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetSizepixelsDropDownValueChanged, true);
            app.TargetSizepixelsDropDown.Value = '1152x1152';
            app.SLMconnectedLampLabel = uilabel(app.GridLayout2);
            app.SLMconnectedLampLabel.Text = 'SLM connected?';
            app.SLMconnectedLamp = uilamp(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloApp_Lito

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end